classdef Ufuk_Kiyak < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UfukKyak19050211013Label  matlab.ui.control.Label
        RESETButton               matlab.ui.control.Button
        STOPButton                matlab.ui.control.Button
        PLOTButton                matlab.ui.control.Button
        DropDown                  matlab.ui.control.DropDown
        SELECTYOURPORTLabel       matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Design malzemelerinin yukarıda tanımlanması
    % Aşağıda tanımlamalar yapılmıştır
    properties (Access = private)
    serialPort % Seri port nesnesi
    h % Plot handle
    stopPlotting % Plotlamayı durdurmak için bayrak
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: PLOTButton, UIFigure
        function PLOTButtonPushed(app, event)
            % Seri portu ayarla ve aç
            if isempty(app.serialPort) || ~isvalid(app.serialPort)
                app.serialPort = serialport(app.DropDown.Value, 9600);
            end

            % Grafik eksenini hazırla ve grafiği başlat
            app.h = plot(app.UIAxes, nan, nan);  % Grafiği başlat
            xlabel(app.UIAxes, 'EKG');
            ylabel(app.UIAxes, 'mV');
            title(app.UIAxes, 'time');

            app.stopPlotting = false;  % Durumu kontrol etmek için bir bayrak

            % Seri porttan veri oku ve grafiği güncelle
            while isvalid(app.serialPort) && ~app.stopPlotting
                data = read(app.serialPort, 1, "uint16");  % Bir uint16 veri oku
                if ~isempty(data)
                    newData = double(data);  % Plotlama için double'a çevir
                    existingData = get(app.h, 'YData');
                    newData = [existingData, newData];  % Yeni veriyi ekle
                    set(app.h, 'YData', newData);
                    set(app.h, 'XData', 0:length(newData)-1);  % X verisini güncelle
                    drawnow;
                end
            end

            % Seri portu kapatma işlemi
            if isvalid(app.serialPort)
                clear app.serialPort;
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
 % Plotlamayı durdur
            app.stopPlotting = true;

            % Seri portu temizle
            if ~isempty(app.serialPort) && isvalid(app.serialPort)
                clear app.serialPort;  % Seri portu sonlandır
                app.serialPort = [];  % Handles'dan seri portu temizle
            end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            % Eğer serialPort nesnesi varsa ve geçerliyse
    if ~isempty(app.serialPort) && isvalid(app.serialPort)
        % Seri portu kapat
        app.serialPort.flush();
        configureTermination(app.serialPort, 'off'); % Eğer portun düzgün kapanmasını sağlamak için ekstra bir adım gerekiyorsa
        app.serialPort.close();

        % Seri port nesnesini sil
        delete(app.serialPort);
        app.serialPort = [];
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'hsv');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'EKG')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, '(mV)')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'summer')
            app.SELECTYOURPORTLabel = uilabel(app.UIFigure);
            app.SELECTYOURPORTLabel.Text = '';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'SEÇ', 'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12', 'COM13', 'COM14', 'COM15'};
            app.DropDown.Value = 'SEÇ';
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.UfukKyak19050211013Label = uilabel(app.UIFigure);
            app.UfukKyak19050211013Label.Text = {'Ufuk Kıyak'; '19050211013'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ufuk_Kiyak

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end