classdef Hikmet_Unuvar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        izgiRengiDropDown       matlab.ui.control.DropDown
        izgiRengiDropDownLabel  matlab.ui.control.Label
        SaveasmatButton         matlab.ui.control.Button
        RESETPORTButton         matlab.ui.control.Button
        Image2                  matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        STOPButton              matlab.ui.control.Button
        PLOTButton              matlab.ui.control.Button
        SaveasPNGButton         matlab.ui.control.Button
        PORTDropDown            matlab.ui.control.DropDown
        SELECTYOURPORTLabel     matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    serialPort % Seri port nesnesi
    h % Plot handle
    stopPlotting % Plotlamayı durdurmak için bayrak
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: PLOTButton, UIFigure
        function PLOTButtonPushed(app, event)
    % Seri portu ayarla ve aç
    if isempty(app.serialPort) || ~isvalid(app.serialPort)
        app.serialPort = serialport(app.PORTDropDown.Value, 9600);
    end

    % Renk seçimini al
    chosenColor = app.izgiRengiDropDown.Value;
    
    % Seçilen renge göre çizgi rengini belirle
    if strcmp(chosenColor, 'Kırmızı')
        lineColor = 'r';  % MATLAB'da kırmızı için 'r'
    elseif strcmp(chosenColor, 'Mavi')
        lineColor = 'b';  % MATLAB'da mavi için 'b'
    else
        lineColor = 'k';  % Varsayılan renk siyah
    end

    % Grafik eksenini hazırla ve grafiği başlat
    app.h = plot(app.UIAxes, nan, nan, 'Color', lineColor);  % Grafiği belirtilen renkle başlat
    xlabel(app.UIAxes, 'Time (ms)');
    ylabel(app.UIAxes, 'ECG Signal');
    title(app.UIAxes, 'Real-time ECG Graph');

    app.stopPlotting = false;  % Durumu kontrol etmek için bir bayrak

    % Seri porttan veri oku ve grafiği güncelle
    while isvalid(app.serialPort) && ~app.stopPlotting
        data = read(app.serialPort, 1, "uint16");  % Bir uint16 veri oku
        if ~isempty(data)
            newData = double(data);  % Plotlama için double'a çevir
            existingData = get(app.h, 'YData');
            newData = [existingData, newData];  % Yeni veriyi ekle
            set(app.h, 'YData', newData);
            set(app.h, 'XData', 0:length(newData)-1);  % X verisini güncelle
            drawnow;
        end
    end

    % Seri portu kapatma işlemi
    if isvalid(app.serialPort)
        clear app.serialPort;
    end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
 % Plotlamayı durdur
            app.stopPlotting = true;

            % Seri portu temizle
            if ~isempty(app.serialPort) && isvalid(app.serialPort)
                clear app.serialPort;  % Seri portu sonlandır
                app.serialPort = [];  % Handles'dan seri portu temizle
            end
            msgbox('Stopped');
        end

        % Button pushed function: SaveasPNGButton
        function SaveasPNGButtonPushed(app, event)
   % Kullanıcıdan dosya adı ve konumunu seçmesini iste
    [file, path] = uiputfile('*.png', 'Save Image as PNG');
    if isequal(file, 0) || isequal(path, 0)
        % Kullanıcı iptal etti
        disp('User canceled save operation');
    else
        % Dosya yolunu birleştir
        filename = fullfile(path, file);

        % UIAxes'in içeriğini, Position özelliği kullanılarak belirlenen alanı yakalayarak al
        axImg = getframe(app.UIFigure, app.UIAxes.Position);
        imgData = axImg.cdata;

        % Görüntüyü PNG olarak kaydet
        imwrite(imgData, filename, 'png');

        % Kullanıcıya başarılı mesajı göster
        disp(['Saved as: ', filename]);
    end
        end

        % Button pushed function: RESETPORTButton
        function RESETPORTButtonPushed(app, event)
            % Eğer serialPort nesnesi varsa ve geçerliyse
    if ~isempty(app.serialPort) && isvalid(app.serialPort)
        % Seri portu kapat
        app.serialPort.flush();
        configureTermination(app.serialPort, 'off'); % Eğer portun düzgün kapanmasını sağlamak için ekstra bir adım gerekiyorsa
        app.serialPort.close();

        % Seri port nesnesini sil
        delete(app.serialPort);
        app.serialPort = [];
    end

    % Kullanıcıya seri portların resetlendiğini bildir
    msgbox('All serial ports have been reset.');
        end

        % Button pushed function: SaveasmatButton
        function SaveasmatButtonPushed(app, event)
            % Kullanıcıdan kaydetmek için dosya adı ve yolu iste
    [file, path] = uiputfile('*.mat', 'Save Data as MAT-file');
    if isequal(file, 0) || isequal(path, 0)
        disp('User canceled the save operation.');
        return;
    else
        % Tam dosya adı oluştur
        filename = fullfile(path, file);
    end

    % Grafikten verileri al
    data = get(app.h, 'YData');  % 'app.h' grafik çizgi nesnenizdir, bu nesnenin tanımlı olduğundan emin olun

    % Verileri .mat dosyası olarak kaydet
    try
        save(filename, 'data');
        disp(['Data saved successfully to ', filename]);
    catch ME
        % Kaydetme işlemi sırasında bir hata oluşursa kullanıcıya bildir
        disp('Failed to save data:');
        disp(ME.message);
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'hsv');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'EKG GRAFİĞİ Hikmet Ünüvar 19050211059')
            xlabel(app.UIAxes, 'Zaman (mili saniye)')
            ylabel(app.UIAxes, 'Gerilim (mili Volt)')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'summer')
            app.SELECTYOURPORTLabel = uilabel(app.UIFigure);
            app.SELECTYOURPORTLabel.Text = 'PORT';
            app.PORTDropDown = uidropdown(app.UIFigure);
            app.PORTDropDown.Items = {'SEÇİNİZ', 'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12', 'COM13', 'COM14', 'COM15'};
            app.PORTDropDown.Value = 'SEÇİNİZ';
            app.SaveasPNGButton = uibutton(app.UIFigure, 'push');
            app.SaveasPNGButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasPNGButtonPushed, true);
            app.SaveasPNGButton.Text = 'Save as PNG';
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Circuit-DiagramConnection-between-Arduino-and-ECG-Sensor-AD8232.jpg');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'body.jpg');
            app.RESETPORTButton = uibutton(app.UIFigure, 'push');
            app.RESETPORTButton.ButtonPushedFcn = createCallbackFcn(app, @RESETPORTButtonPushed, true);
            app.RESETPORTButton.Text = 'RESET PORT';
            app.SaveasmatButton = uibutton(app.UIFigure, 'push');
            app.SaveasmatButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasmatButtonPushed, true);
            app.SaveasmatButton.Text = 'Save as .mat';
            app.izgiRengiDropDownLabel = uilabel(app.UIFigure);
            app.izgiRengiDropDownLabel.Text = 'Çizgi Rengi';
            app.izgiRengiDropDown = uidropdown(app.UIFigure);
            app.izgiRengiDropDown.Items = {'Seçiniz', 'Kırmızı', 'Mavi'};
            app.izgiRengiDropDown.Value = 'Seçiniz';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hikmet_Unuvar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end